classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CalculatePotentialTab         matlab.ui.container.Tab
        maxrEditField                 matlab.ui.control.NumericEditField
        maxrEditFieldLabel            matlab.ui.control.Label
        minrEditField                 matlab.ui.control.NumericEditField
        minrEditFieldLabel            matlab.ui.control.Label
        SelectedPathEditField_4       matlab.ui.control.EditField
        SelectedPathEditField_4Label  matlab.ui.control.Label
        SelectInputFileButton         matlab.ui.control.Button
        OrSelectInputFileLabel        matlab.ui.control.Label
        SpecifyWheretoSavePotentialDataLabel  matlab.ui.control.Label
        SelectedPathEditField_3       matlab.ui.control.EditField
        SelectedPathEditField_3Label  matlab.ui.control.Label
        SelectPotentialPathButton     matlab.ui.control.Button
        SpecifyWheretoSaveInputFileLabel  matlab.ui.control.Label
        SpecifyAtomicNumbersofSpeciesLabel  matlab.ui.control.Label
        SelectedPathEditField_2       matlab.ui.control.EditField
        SelectedPathEditField_2Label  matlab.ui.control.Label
        SelectInputPathButton         matlab.ui.control.Button
        CalculateCoulombPotentialandSaveDataButton  matlab.ui.control.Button
        Z2EditField                   matlab.ui.control.NumericEditField
        Z2EditFieldLabel              matlab.ui.control.Label
        Z1EditField                   matlab.ui.control.NumericEditField
        Z1EditFieldLabel              matlab.ui.control.Label
        WriteInputFileButton          matlab.ui.control.Button
        TabGroup2                     matlab.ui.container.TabGroup
        LINEARTab                     matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        LOGTab                        matlab.ui.container.Tab
        UIAxes_2                      matlab.ui.control.UIAxes
        FitPotentialTab               matlab.ui.container.Tab
        OrSelectYourOwnPotentialFileLabel  matlab.ui.control.Label
        DataLocationEditField         matlab.ui.control.EditField
        DataLocationEditFieldLabel    matlab.ui.control.Label
        SelectedPathEditField         matlab.ui.control.EditField
        SelectedPathEditFieldLabel    matlab.ui.control.Label
        SelectPotentialFileButton     matlab.ui.control.Button
        numberEditField               matlab.ui.control.NumericEditField
        numberEditFieldLabel          matlab.ui.control.Label
        WriteButton                   matlab.ui.control.Button
        DataSetNameEditField          matlab.ui.control.EditField
        DataSetNameEditFieldLabel     matlab.ui.control.Label
        DirectoryEditField            matlab.ui.control.EditField
        DirectoryEditFieldLabel       matlab.ui.control.Label
        matfileEditField              matlab.ui.control.EditField
        matfileEditFieldLabel         matlab.ui.control.Label
        CalculateScatteringIntegralsTab  matlab.ui.container.Tab
        CalcuateTransportCoefficientsTab  matlab.ui.container.Tab
        CalcuateDSMCCoefficientsTab   matlab.ui.container.Tab
    end

    %these are properties that I utilize across different callbacks
    properties (Access = public)
        matfile % Description
        directory
        DataSetName
        usrnumber
        selpath
        selpath2
        selpath3
        selpath4
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup.FontName="Courier";
            app.TabGroup.FontWeight = 'bold';
            app.TabGroup2.FontName="Courier";
            app.TabGroup2.FontWeight = 'bold';
        end

        % Value changed function: matfileEditField
        function matfileEditFieldValueChanged(app, event)
            value = app.matfileEditField.Value;
            
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            %this is test code
            app.matfile = app.matfileEditField.Value;
            app.directory = app.DirectoryEditField.Value;
            app.DataSetName = app.DataSetNameEditField.Value; 
            app.usrnumber = app.numberEditField.Value;
            filename  = [app.selpath '/file.m'];
            FID=fopen(filename,'a');
            fprintf(FID, ' matfile = %s;\n',app.matfile);
            fprintf(FID, ' directory = %s;\n',app.directory);
            fprintf(FID, ' DataSetName = %s;\n',app.DataSetName);
            fprintf(FID, ' User_Number = %d;\n',app.usrnumber);
            fprintf(FID, ' FilePath = "%s";\n',app.selpath);
            fclose(FID);
            
        end

        % Button pushed function: SelectPotentialFileButton
        function SelectPotentialFileButtonPushed(app, event)
            %allows you to select input file from directory
            app.selpath  = uigetdir;
            app.SelectedPathEditField.Value = app.selpath;
        end

        % Button pushed function: 
        % CalculateCoulombPotentialandSaveDataButton
        function CalculateCoulombPotentialandSaveDataButtonPushed(app, event)
            %executes run_calcpotential, plots data, pushes data
            %location to next tab
            addpath('../src')
            run_calcpotential(app.selpath3);
            datalocation = [app.selpath3 '/coulumb_data.csv'];
            t = readmatrix(datalocation);
            xvals = t(:,1);
            yvals = t(:,2);
            plot(app.UIAxes,xvals,yvals,'-o',MarkerFaceColor='b');
            plot(app.UIAxes_2,xvals,yvals,'-o',MarkerFaceColor='b');
            yscale(app.UIAxes_2,'log');
            xscale(app.UIAxes_2,'linear');
            app.DataLocationEditField.Value = app.selpath3;
        end

        % Button pushed function: SelectInputPathButton
        function SelectInputPathButtonPushed(app, event)
            %allows you to select where input file will save
            app.selpath2  = uigetdir;
            app.SelectedPathEditField_2.Value = app.selpath2;
        end

        % Button pushed function: WriteInputFileButton
        function WriteInputFileButtonPushed(app, event)
            %creates input file with user-specified parameters
            %saves input file in user specified directory
            z1 = app.Z1EditField.Value;
            z2 = app.Z2EditField.Value;
            minr = app.minrEditField.Value;
            maxr = app.maxrEditField.Value;
            filename  = [app.selpath2 '/inputfile.m'];
            inputfile=fopen(filename,'w');
            fprintf(inputfile, 'Z1 = %i;\n',z1);
            fprintf(inputfile, 'Z2 = %i;\n',z2);
            fprintf(inputfile, 'minR = %f;\n',minr);
            fprintf(inputfile, 'maxR = %f;\n',maxr);
            fclose(inputfile);
            app.SelectedPathEditField_4.Value = filename;
        end

        % Button pushed function: SelectPotentialPathButton
        function SelectPotentialPathButtonPushed(app, event)
            %allows user to specify where potential data will save
            app.selpath3  = uigetdir;
            app.SelectedPathEditField_3.Value = app.selpath3;
        end

        % Button pushed function: SelectInputFileButton
        function SelectInputFileButtonPushed(app, event)
            %allows user to select their own input file
            [baseName, folder] = uigetfile();
            app.selpath4 = fullfile(folder, baseName);
            app.SelectedPathEditField_4.Value = app.selpath4;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalculatePotentialTab = uitab(app.TabGroup);
            app.CalculatePotentialTab.Title = 'Calculate Potential';
            app.TabGroup2 = uitabgroup(app.CalculatePotentialTab);
            app.LINEARTab = uitab(app.TabGroup2);
            app.LINEARTab.Title = 'LINEAR';
            app.UIAxes = uiaxes(app.LINEARTab);
            title(app.UIAxes, 'Interatomic Potential')
            xlabel(app.UIAxes, 'Interatomic Distance (Å)')
            ylabel(app.UIAxes, 'Energy (eV)')
            zlabel(app.UIAxes, 'Z')
            app.LOGTab = uitab(app.TabGroup2);
            app.LOGTab.Title = 'LOG';
            app.UIAxes_2 = uiaxes(app.LOGTab);
            title(app.UIAxes_2, 'Interatomic Potential')
            xlabel(app.UIAxes_2, 'Interatomic Distance (Å)')
            ylabel(app.UIAxes_2, 'Energy (eV)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XScale = 'log';
            app.UIAxes_2.YScale = 'log';
            app.WriteInputFileButton = uibutton(app.CalculatePotentialTab, 'push');
            app.WriteInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @WriteInputFileButtonPushed, true);
            app.WriteInputFileButton.Text = 'Write Input File';
            app.Z1EditFieldLabel = uilabel(app.CalculatePotentialTab);
            app.Z1EditFieldLabel.Text = 'Z1';
            app.Z1EditField = uieditfield(app.CalculatePotentialTab, 'numeric');
            app.Z2EditFieldLabel = uilabel(app.CalculatePotentialTab);
            app.Z2EditFieldLabel.Text = 'Z2';
            app.Z2EditField = uieditfield(app.CalculatePotentialTab, 'numeric');
            app.CalculateCoulombPotentialandSaveDataButton = uibutton(app.CalculatePotentialTab, 'push');
            app.CalculateCoulombPotentialandSaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateCoulombPotentialandSaveDataButtonPushed, true);
            app.CalculateCoulombPotentialandSaveDataButton.Text = 'Calculate Coulomb Potential and Save Data';
            app.SelectInputPathButton = uibutton(app.CalculatePotentialTab, 'push');
            app.SelectInputPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputPathButtonPushed, true);
            app.SelectInputPathButton.Text = 'Select Input Path';
            app.SelectedPathEditField_2Label = uilabel(app.CalculatePotentialTab);
            app.SelectedPathEditField_2Label.Text = 'Selected Path';
            app.SelectedPathEditField_2 = uieditfield(app.CalculatePotentialTab, 'text');
            app.SpecifyAtomicNumbersofSpeciesLabel = uilabel(app.CalculatePotentialTab);
            app.SpecifyAtomicNumbersofSpeciesLabel.Text = 'Specify Atomic Numbers of Species';
            app.SpecifyWheretoSaveInputFileLabel = uilabel(app.CalculatePotentialTab);
            app.SpecifyWheretoSaveInputFileLabel.Text = 'Specify Where to Save Input File';
            app.SelectPotentialPathButton = uibutton(app.CalculatePotentialTab, 'push');
            app.SelectPotentialPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPotentialPathButtonPushed, true);
            app.SelectPotentialPathButton.Text = 'Select Potential Path';
            app.SelectedPathEditField_3Label = uilabel(app.CalculatePotentialTab);
            app.SelectedPathEditField_3Label.Text = 'Selected Path';
            app.SelectedPathEditField_3 = uieditfield(app.CalculatePotentialTab, 'text');
            app.SpecifyWheretoSavePotentialDataLabel = uilabel(app.CalculatePotentialTab);
            app.SpecifyWheretoSavePotentialDataLabel.Text = 'Specify Where to Save Potential Data';
            app.OrSelectInputFileLabel = uilabel(app.CalculatePotentialTab);
            app.OrSelectInputFileLabel.Text = 'Or Select Input File';
            app.SelectInputFileButton = uibutton(app.CalculatePotentialTab, 'push');
            app.SelectInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputFileButtonPushed, true);
            app.SelectInputFileButton.Text = 'Select Input File';
            app.SelectedPathEditField_4Label = uilabel(app.CalculatePotentialTab);
            app.SelectedPathEditField_4Label.Text = 'Selected Path';
            app.SelectedPathEditField_4 = uieditfield(app.CalculatePotentialTab, 'text');
            app.minrEditFieldLabel = uilabel(app.CalculatePotentialTab);
            app.minrEditFieldLabel.Text = 'min r ';
            app.minrEditField = uieditfield(app.CalculatePotentialTab, 'numeric');
            app.maxrEditFieldLabel = uilabel(app.CalculatePotentialTab);
            app.maxrEditFieldLabel.Text = 'max r';
            app.maxrEditField = uieditfield(app.CalculatePotentialTab, 'numeric');
            app.FitPotentialTab = uitab(app.TabGroup);
            app.FitPotentialTab.Title = 'Fit Potential';
            app.matfileEditFieldLabel = uilabel(app.FitPotentialTab);
            app.matfileEditFieldLabel.Text = 'matfile';
            app.matfileEditField = uieditfield(app.FitPotentialTab, 'text');
            app.matfileEditField.ValueChangedFcn = createCallbackFcn(app, @matfileEditFieldValueChanged, true);
            app.DirectoryEditFieldLabel = uilabel(app.FitPotentialTab);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.FitPotentialTab, 'text');
            app.DataSetNameEditFieldLabel = uilabel(app.FitPotentialTab);
            app.DataSetNameEditFieldLabel.Text = 'DataSetName';
            app.DataSetNameEditField = uieditfield(app.FitPotentialTab, 'text');
            app.WriteButton = uibutton(app.FitPotentialTab, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = 'Write';
            app.numberEditFieldLabel = uilabel(app.FitPotentialTab);
            app.numberEditFieldLabel.Text = 'number';
            app.numberEditField = uieditfield(app.FitPotentialTab, 'numeric');
            app.SelectPotentialFileButton = uibutton(app.FitPotentialTab, 'push');
            app.SelectPotentialFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPotentialFileButtonPushed, true);
            app.SelectPotentialFileButton.Text = 'Select Potential File';
            app.SelectedPathEditFieldLabel = uilabel(app.FitPotentialTab);
            app.SelectedPathEditFieldLabel.Text = 'Selected Path';
            app.SelectedPathEditField = uieditfield(app.FitPotentialTab, 'text');
            app.DataLocationEditFieldLabel = uilabel(app.FitPotentialTab);
            app.DataLocationEditFieldLabel.Text = 'Data Location';
            app.DataLocationEditField = uieditfield(app.FitPotentialTab, 'text');
            app.OrSelectYourOwnPotentialFileLabel = uilabel(app.FitPotentialTab);
            app.OrSelectYourOwnPotentialFileLabel.Text = 'Or Select Your Own Potential File';
            app.CalculateScatteringIntegralsTab = uitab(app.TabGroup);
            app.CalculateScatteringIntegralsTab.Title = 'Calculate Scattering Integrals';
            app.CalcuateTransportCoefficientsTab = uitab(app.TabGroup);
            app.CalcuateTransportCoefficientsTab.Title = 'Calcuate Transport Coefficients';
            app.CalcuateDSMCCoefficientsTab = uitab(app.TabGroup);
            app.CalcuateDSMCCoefficientsTab.Title = 'Calcuate DSMC Coefficients';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end